classdef CAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CASUIFigure                matlab.ui.Figure
        MsgBox                     matlab.ui.control.TextArea
        FileselectionPanel         matlab.ui.container.Panel
        SelectfolderButton         matlab.ui.control.Button
        ListBox                    matlab.ui.control.ListBox
        ProcessandexportPanel      matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        DropDown                   matlab.ui.control.DropDown
        ExportButton               matlab.ui.control.Button
        ProcessPanel               matlab.ui.container.Panel
        ProcessButton              matlab.ui.control.Button
        ParametersPanel            matlab.ui.container.Panel
        PixelsizeumEditFieldLabel  matlab.ui.control.Label
        PixelSizeEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        pr_particleData = struct;
        pr_fileList;
        pr_list;
        pr_inputPath;
        pr_exportPath;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            [inputPath,exportPath,list] = setFolderPathAuxFn(app);
            if isempty(exportPath)
                app.MsgBox.Value = [app.MsgBox.Value ;...
                    sprintf('%s','Error: no path is selected.')];
            else
                app.pr_inputPath = inputPath;
                app.pr_exportPath = exportPath;
                app.pr_list = list;
                app.pr_fileList = retrieveFilesAuxFn(app.pr_inputPath,'.tif');
                populateListBoxAuxFn(app);
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            detectFn(app);
            populateDropDownAuxFn(app.ListBox,app.DropDown);
            displayImageAuxFn(app,app.UIAxes,app.DropDown);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            displayImageAuxFn(app,app.UIAxes,app.DropDown);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportFn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CASUIFigure = uifigure('Visible', 'off');
            app.CASUIFigure.Name = 'CAS';
            app.MsgBox = uitextarea(app.CASUIFigure);
            app.FileselectionPanel = uipanel(app.CASUIFigure);
            app.FileselectionPanel.Title = 'File selection';
            app.SelectfolderButton = uibutton(app.FileselectionPanel, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.ListBox = uilistbox(app.FileselectionPanel);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.ProcessandexportPanel = uipanel(app.CASUIFigure);
            app.ProcessandexportPanel.Title = 'Process and export';
            app.UIAxes = uiaxes(app.ProcessandexportPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DropDown = uidropdown(app.ProcessandexportPanel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.ExportButton = uibutton(app.ProcessandexportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ProcessPanel = uipanel(app.CASUIFigure);
            app.ProcessPanel.Title = 'Process';
            app.ProcessButton = uibutton(app.ProcessPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ParametersPanel = uipanel(app.CASUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.PixelsizeumEditFieldLabel = uilabel(app.ParametersPanel);
            app.PixelsizeumEditFieldLabel.Text = 'Pixel size (um)';
            app.PixelSizeEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PixelSizeEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.CASUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CAS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CASUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CASUIFigure)
        end
    end
end